classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainPanel                  matlab.ui.container.Panel
        Tabs                       matlab.ui.container.TabGroup
        Graphs                     matlab.ui.container.Tab
        ControlPanel               matlab.ui.container.Panel
        GraphsPanel                matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        FourrierButton             matlab.ui.control.Button
        SmoothdataButton           matlab.ui.control.Button
        SmoothButton               matlab.ui.control.Button
        Button4                    matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        Data                       matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        openFilePanel              matlab.ui.container.Panel
        OpenfileButton             matlab.ui.control.Button
        CurrentfileEditFieldLabel  matlab.ui.control.Label
        CurrentfileEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx');
            if isequal(file,0)          
                msgbox('Please input an Excel file')
            else
                t = readtable(fullfile(path, file));
                app.UITable.Data = t;
            end
            fullpathname = strcat(path, file);
            app.CurrentfileEditField.Value = fullpathname;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainPanel = uipanel(app.UIFigure);
            app.Tabs = uitabgroup(app.MainPanel);
            app.Graphs = uitab(app.Tabs);
            app.Graphs.Title = 'Graphs';
            app.ControlPanel = uipanel(app.Graphs);
            app.GraphsPanel = uipanel(app.Graphs);
            app.UIAxes = uiaxes(app.GraphsPanel);
            title(app.UIAxes, 'Before operation(s)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.GraphsPanel);
            title(app.UIAxes2, 'After operation(s)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.FourrierButton = uibutton(app.Graphs, 'push');
            app.FourrierButton.Text = 'Fourrier';
            app.SmoothdataButton = uibutton(app.Graphs, 'push');
            app.SmoothdataButton.Text = 'Smooth data';
            app.SmoothButton = uibutton(app.Graphs, 'push');
            app.SmoothButton.Text = 'Smooth';
            app.Button4 = uibutton(app.Graphs, 'push');
            app.Button4.Text = 'Button4';
            app.ApplyButton = uibutton(app.Graphs, 'push');
            app.ApplyButton.Text = 'Apply';
            app.Data = uitab(app.Tabs);
            app.Data.Title = 'Data';
            app.UITable = uitable(app.Data);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.openFilePanel = uipanel(app.MainPanel);
            app.OpenfileButton = uibutton(app.openFilePanel, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
            app.CurrentfileEditFieldLabel = uilabel(app.openFilePanel);
            app.CurrentfileEditFieldLabel.Text = 'Current file:';
            app.CurrentfileEditField = uieditfield(app.openFilePanel, 'text');
            app.CurrentfileEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end